classdef slicer_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SlicerGUILabel                matlab.ui.control.Label
        LengthmmEditFieldLabel        matlab.ui.control.Label
        LengthmmEditField             matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel         matlab.ui.control.Label
        WidthmmEditField              matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel        matlab.ui.control.Label
        HeightmmEditField             matlab.ui.control.NumericEditField
        VoxelsperLayerLengthwiseEditFieldLabel  matlab.ui.control.Label
        VoxelsperLayerLengthwiseEditField  matlab.ui.control.NumericEditField
        LayerHeightmmEditFieldLabel   matlab.ui.control.Label
        LayerHeightmmEditField        matlab.ui.control.NumericEditField
        VoxelsperLayerWidthwiseEditFieldLabel  matlab.ui.control.Label
        VoxelsperLayerWidthwiseEditField  matlab.ui.control.NumericEditField
        VoxelMarginmmEditFieldLabel   matlab.ui.control.Label
        VoxelMarginmmEditField        matlab.ui.control.NumericEditField
        VoxelPaddingmmEditFieldLabel  matlab.ui.control.Label
        VoxelPaddingmmEditField       matlab.ui.control.NumericEditField
        SubmitButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            length = app.LengthmmEditField;
            width = app.WidthmmEditField;
            height = app.HeightmmEditField;
            layer_height = app.LayerHeightmmEditField;
            voxels_per_length = app.VoxelsperLayerLengthwiseEditField;
            voxels_per_width = app.VoxelsperLayerWidthwiseEditField;
            voxel_margin = app.VoxelMarginmmEditField;
            voxel_padding = app.VoxelPaddingmmEditField;
            %TODO: here it should send parameters to gen_cube()
            %Need to make that script a function to send parameters
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlicerGUILabel = uilabel(app.UIFigure);
            app.SlicerGUILabel.Text = 'Slicer GUI';
            app.LengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelsperLayerLengthwiseEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelsperLayerLengthwiseEditFieldLabel.Text = 'Voxels per Layer (Length-wise)';
            app.VoxelsperLayerLengthwiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerHeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.LayerHeightmmEditFieldLabel.Text = 'Layer Height (mm)';
            app.LayerHeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelsperLayerWidthwiseEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelsperLayerWidthwiseEditFieldLabel.Text = 'Voxels per Layer (Width-wise)';
            app.VoxelsperLayerWidthwiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelMarginmmEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelMarginmmEditFieldLabel.Text = 'Voxel Margin (mm)';
            app.VoxelMarginmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoxelPaddingmmEditFieldLabel = uilabel(app.UIFigure);
            app.VoxelPaddingmmEditFieldLabel.Text = 'Voxel Padding (mm)';
            app.VoxelPaddingmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slicer_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end